classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SolarenergyparametersPanel      matlab.ui.container.Panel
        btn_solarEnergy                 matlab.ui.control.StateButton
        edit_panelEfficiency            matlab.ui.control.NumericEditField
        EfficiencyLabel_2               matlab.ui.control.Label
        edit_panelArea                  matlab.ui.control.NumericEditField
        Panelaream2Label                matlab.ui.control.Label
        WindturbineparametersPanel      matlab.ui.container.Panel
        btn_windEnergy                  matlab.ui.control.StateButton
        edit_turbineEfficiency          matlab.ui.control.NumericEditField
        EfficiencyLabel                 matlab.ui.control.Label
        edit_airDensity                 matlab.ui.control.NumericEditField
        Airdensitykgm3Label             matlab.ui.control.Label
        edit_bladeLength                matlab.ui.control.NumericEditField
        BladelengthmEditFieldLabel      matlab.ui.control.Label
        dropdown_hubH                   matlab.ui.control.DropDown
        HubheightmLabel                 matlab.ui.control.Label
        Result                          matlab.ui.container.Panel
        solarOutput_graph               matlab.ui.control.UIAxes
        solarEnergy_graph               matlab.ui.control.UIAxes
        windOutput_graph                matlab.ui.control.UIAxes
        windEnergy_graph                matlab.ui.control.UIAxes
        Input                           matlab.ui.container.Panel
        btn_load                        matlab.ui.control.Button
        edit_endMonth                   matlab.ui.control.EditField
        StartDateyyyymmddoptionalLabel  matlab.ui.control.Label
        edit_startMonth                 matlab.ui.control.EditField
        StartDateyyyymmddLabel          matlab.ui.control.Label
        edit_location                   matlab.ui.control.EditField
        LocationLabel                   matlab.ui.control.Label
        lbl_loading                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        base_url = "https://weather.visualcrossing.com/VisualCrossingWebServices/rest/services/timeline/"
        API_KEY = '3KAJKHWT3UEMRQWF2ABKVVVZE'

%         location = '' % Description
        weather_data = [] % Description
    end
    
    methods (Access = private)
        
        function weatherData = fetchWeatherData(app, location, start_date, end_date)
            try
                api_url = sprintf('%s%s/%s/%s?unitGroup=metric&key=%s&include=hours&elements=datetime,windspeed,windspeed50,windspeed80,windspeed100,dniradiation,difradiation', app.base_url, location, start_date, end_date, app.API_KEY);                
                weatherData = webread(api_url);
            catch ME
                % Handle any errors that occur during the HTTP request
                error('Error fetching data from the API: %s', ME.message);
            end
        end
        
        function plot_windEnergy_data(app, monthly_wind_Es, eff)
            % Get the number of entries
            numWindEntries = numel(monthly_wind_Es);
            
            % Initialize arrays
            windEnergies = zeros(numWindEntries, 1);
            productable_windEnergies = zeros(numWindEntries, 1);
            
            % Extract month and energy information for wind
            for i = 1:numWindEntries
                months(i) = monthly_wind_Es(i).month;
                windEnergies(i) = monthly_wind_Es(i).energy / (10^6); % Convert W*h to KW*h
                productable_windEnergies(i) = windEnergies(i)/eff;
            end

            max_windE = max(windEnergies);
            min_windE = min(windEnergies);
            avg_windE = mean(windEnergies);
            
            max_p_windE = max(productable_windEnergies);
            min_p_windE = min(productable_windEnergies);
            avg_p_windE = mean(productable_windEnergies);

            cla(app.windEnergy_graph);
            cla(app.windOutput_graph);
            % Available wind energy Plot
            windE_ax = app.windEnergy_graph;
            plot(windE_ax, months, windEnergies, 'r-');
            windE_ax.YLim = [min_windE - 1, max_windE + 1];
            yline(windE_ax, min_windE,'--b',sprintf('Min: %.2f', min_windE));
            yline(windE_ax, max_windE,'--r',sprintf('Max: %.2f', max_windE));
            yline(windE_ax, avg_windE,'--k',sprintf('Avg: %.2f', avg_windE));            
%             legend(temp_ax, 'Temperature', 'Max Temp', 'Min Temp', 'Avg Temp', 'Location', 'best


            % Productiable wind energy Plot
            p_windE_ax = app.windOutput_graph;
            plot(p_windE_ax, months, productable_windEnergies, 'b-');
            grid(p_windE_ax, "on");
            hold(p_windE_ax, 'on');
            p_windE_ax.YLim = [min_p_windE - 1, max_p_windE + 1];
            yline(p_windE_ax, min_p_windE,'--b',sprintf('Min: %.2f%', min_p_windE));
            yline(p_windE_ax, max_p_windE,'--r',sprintf('Max: %.2f%', max_p_windE));
            yline(p_windE_ax, avg_p_windE,'--k',sprintf('Avg: %.2f%', avg_p_windE));
%             legend('Humidity', 'Max Humidity', 'Min Humidity', 'Avg Humidity', 'Location', 'best');           
            

            % Optionally, save the figure to a file
            % saveas(fig, 'weather_plot.png');
            
            % Optionally, display the figure
            % imshow('weather_plot.png');
        end

        function plot_solarEnergy_data(app, monthly_solar_Es, eff)
            % Get the number of entries
            numWindEntries = numel(monthly_solar_Es);
            
            % Initialize arrays
            solarEnergies = zeros(numWindEntries, 1);
            productable_solarEnergies = zeros(numWindEntries, 1);
            
            % Extract month and energy information for wind
            for i = 1:numWindEntries
                months(i) = monthly_solar_Es(i).month;
                solarEnergies(i) = monthly_solar_Es(i).energy / (10^6); % Convert W*h to KW*h
                productable_solarEnergies(i) = solarEnergies(i)/eff;
            end
            
            max_solarE = max(solarEnergies);
            min_solarE = min(solarEnergies);
            avg_solarE = mean(solarEnergies);
            
            max_p_solarE = max(productable_solarEnergies);
            min_p_solarE = min(productable_solarEnergies);
            avg_wind_p_solarE = mean(productable_solarEnergies);
            
            cla(app.solarEnergy_graph);
            cla(app.solarOutput_graph);
            
            
            % Available Solar Energy Plot
            solarE_ax = app.solarEnergy_graph;
            plot(solarE_ax, months, solarEnergies, 'g-');
            grid(solarE_ax, "on");
            hold(solarE_ax, 'on');
            solarE_ax.YLim = [min_solarE - 1, max_solarE + 1];
            yline(solarE_ax, min_solarE,'--b',sprintf('Min: %.2f', min_solarE));
            yline(solarE_ax, max_solarE,'--r',sprintf('Max: %.2f', max_solarE));
            yline(solarE_ax, avg_solarE,'--k',sprintf('Avg: %.2f', avg_solarE));
%             legend('Precipitation', 'Max Precipitation', 'Min Precipitation', 'Avg Precipitation', 'Location', 'best');
            
            % Productable solar energy Plot
            p_solarE_ax = app.solarOutput_graph;
            plot(p_solarE_ax, months, productable_solarEnergies, 'm-');
            hold(p_solarE_ax, 'on');
            p_solarE_ax.YLim = [min_p_solarE - 1, max_p_solarE + 1];
            yline(p_solarE_ax, min_p_solarE,'--b',sprintf('Min: %.2f', min_p_solarE));
            yline(p_solarE_ax, max_p_solarE,'--r',sprintf('Max: %.2f', max_p_solarE));
            yline(p_solarE_ax, avg_wind_p_solarE,'--k',sprintf('Avg: %.2f', avg_wind_p_solarE));
%             legend('Wind Speed', 'Max Wind Speed', 'Min Wind Speed', 'Avg Wind Speed', 'Location', 'best');

            % Optionally, save the figure to a file
            % saveas(fig, 'weather_plot.png');
            
            % Optionally, display the figure
            % imshow('weather_plot.png');
        end
        
        function last_day = getLastDayOfMonth(~, dateStr)
            dateParts = split(dateStr, '-');
            year = str2double(dateParts{1});
            month = str2double(dateParts{2});
            last_day = eomday(year, month);        
        end

        function monthlyEnergyArray = calculateMonthlyWindEnergy(~, data, windkey, bladelength, airdensity)
            % Constants
            k = 0.5 * pi * bladelength^2 * airdensity;
        
            % Prepare a map to hold monthly energy sums
            monthlyEnergyMap = containers.Map('KeyType', 'char', 'ValueType', 'double');
            disp(data)
        
            % Iterate over each day in the data
            for i = 1:length(data.days)
                day = data.days(i);
                
                % Extract the year and month from the datetime
                monthKey = day.datetime(1:7);  % 'YYYY-MM' format
                
                % Initialize the monthly key if not already present
                if ~isKey(monthlyEnergyMap, monthKey)
                    monthlyEnergyMap(monthKey) = 0;
                end
                
                % Iterate over each hour in the day
                for j = 1:length(day.hours)
                    hourData = day.hours(j);
                    if(~isempty(hourData.(windkey)))
                        % Calculate the hourly energy
                        hourlyEnergy = k * hourData.(windkey)^3; % [W*h]
                        monthlyEnergyMap(monthKey) = monthlyEnergyMap(monthKey) + hourlyEnergy;
                    end             
                end
            end
        
            % Sort the months and prepare output structure array
            sortedMonths = sort(keys(monthlyEnergyMap));
            monthlyEnergyArray = struct('month', [], 'energy', []);
            
            % Assign energy values to the output array
            for i = 1:length(sortedMonths)
                month_date = datetime(sortedMonths{i}, 'InputFormat', 'yyyy-MM', 'Format', 'yyyy:MM');
                monthlyEnergyArray(i).month = month_date;
                monthlyEnergyArray(i).energy = monthlyEnergyMap(sortedMonths{i});
            end        
        end

        function monthlyEnergyArray = calculateMonthlySolarEnergy(~, data, panelArea)
            % Assumed constant for energy calculation
            kk = panelArea;
        
            % Prepare a map to hold monthly energy sums
            monthlyEnergyMap = containers.Map('KeyType', 'char', 'ValueType', 'double');
        
            % Iterate over each day in the data
            for i = 1:length(data.days)
                day = data.days(i);
                
                % Extract the year and month from the datetime
                monthKey = day.datetime(1:7);  % 'YYYY-MM' format
                
                % Initialize the monthly key if not already present
                if ~isKey(monthlyEnergyMap, monthKey)
                    monthlyEnergyMap(monthKey) = 0;
                end
                
                % Iterate over each hour in the day
                for j = 1:length(day.hours)
                    hourData = day.hours(j);
                    if(isempty(hourData.dniradiation))
                        hourData.dniradiation=0;
                    end
                    if(isempty(hourData.difradiation))
                        hourData.difradiation=0;
                    end
                    hourlyEnergy = kk * (hourData.dniradiation + hourData.difradiation); % [W*h]  
                    monthlyEnergyMap(monthKey) = monthlyEnergyMap(monthKey) + hourlyEnergy;                    
                end
            end
        
            % Sort the months and prepare output structure array
            sortedMonths = sort(keys(monthlyEnergyMap));
            monthlyEnergyArray = struct('month', {}, 'energy', {});
            
            % Assign energy values to the output array
            for i = 1:length(sortedMonths)
                month_date = datetime(sortedMonths{i}, 'InputFormat', 'yyyy-MM', 'Format', 'yyyy:MM');
                monthlyEnergyArray(end+1) = struct('month', month_date, 'energy', monthlyEnergyMap(sortedMonths{i}));
            end        
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_load
        function btn_load_pushed(app, event)
            app.lbl_loading.Visible = true;
            app.lbl_loading.Enable = true;

            location = app.edit_location.Value;
            startDate = sprintf('%s-01', app.edit_startMonth.Value);
            endDate = sprintf('%s-%d', app.edit_endMonth.Value, getLastDayOfMonth(app, app.edit_endMonth.Value));

            if isempty(location)
                errordlg('Location is required!', 'Error');
                return;
            end
            app.weather_data = fetchWeatherData(app, location, startDate, endDate);

            app.lbl_loading.Visible = false;
            app.lbl_loading.Enable = false;
            app.WindturbineparametersPanel.Visible = true;
            app.SolarenergyparametersPanel.Visible = true;
            app.WindturbineparametersPanel.Enable = true;
            app.SolarenergyparametersPanel.Enable = true;
        end

        % Value changed function: btn_windEnergy
        function estimate_windE(app, event)
            height = app.dropdown_hubH.Value;
            if height == 10
                windkey = 'windspeed';
            elseif height == 50
                windkey = 'windspeed50';
            elseif height == 80
                windkey = 'windspeed80';
            elseif height == 100
                windkey = 'windspeed100';
            else
                windkey = 'windspeed50'; % Default case
            end
            balde_length = app.edit_bladeLength.Value;
            air_density = app.edit_airDensity.Value;
            monthly_windEs = calculateMonthlyWindEnergy(app, app.weather_data, windkey, balde_length, air_density)
            wind_eff = app.edit_turbineEfficiency.Value;

            plot_windEnergy_data(app, monthly_windEs, wind_eff)
            
        end

        % Value changed function: btn_solarEnergy
        function estimate_solarE(app, event)
            
            panel_area = app.edit_panelArea.Value;
            monthly_solarEs = calculateMonthlySolarEnergy(app, app.weather_data, panel_area)
            solar_eff = app.edit_panelEfficiency.Value;

            plot_solarEnergy_data(app, monthly_solarEs, solar_eff)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lbl_loading = uilabel(app.UIFigure);
            app.lbl_loading.Enable = 'off';
            app.lbl_loading.Visible = 'off';
            app.lbl_loading.Text = 'Loading data ...';
            app.Input = uipanel(app.UIFigure);
            app.Input.Title = 'Request Weather Data';
            app.LocationLabel = uilabel(app.Input);
            app.LocationLabel.Text = 'Location:';
            app.edit_location = uieditfield(app.Input, 'text');
            app.StartDateyyyymmddLabel = uilabel(app.Input);
            app.StartDateyyyymmddLabel.Text = 'Start Month (yyyy-mm):';
            app.edit_startMonth = uieditfield(app.Input, 'text');
            app.StartDateyyyymmddoptionalLabel = uilabel(app.Input);
            app.StartDateyyyymmddoptionalLabel.Text = 'End Month (yyyy-mm):';
            app.edit_endMonth = uieditfield(app.Input, 'text');
            app.btn_load = uibutton(app.Input, 'push');
            app.btn_load.ButtonPushedFcn = createCallbackFcn(app, @btn_load_pushed, true);
            app.btn_load.Text = 'Load';
            app.Result = uipanel(app.UIFigure);
            app.Result.Title = 'Result';
            app.windEnergy_graph = uiaxes(app.Result);
            title(app.windEnergy_graph, 'Available Wind Energy (MW·h)')
            xlabel(app.windEnergy_graph, 'timestamp')
            ylabel(app.windEnergy_graph, 'Temperature (MW·h)')
            zlabel(app.windEnergy_graph, 'Z')
            app.windOutput_graph = uiaxes(app.Result);
            title(app.windOutput_graph, 'Productable Wind Energy (MW·h)')
            xlabel(app.windOutput_graph, 'timestamp')
            ylabel(app.windOutput_graph, 'Energy (MW·h)')
            zlabel(app.windOutput_graph, 'Z')
            app.solarEnergy_graph = uiaxes(app.Result);
            title(app.solarEnergy_graph, 'Available Solar Energy (MW·h)')
            xlabel(app.solarEnergy_graph, 'timestamp')
            ylabel(app.solarEnergy_graph, 'Energy (MW·h)')
            zlabel(app.solarEnergy_graph, 'Z')
            app.solarOutput_graph = uiaxes(app.Result);
            title(app.solarOutput_graph, 'Productable Solar Energy (MW·h)')
            xlabel(app.solarOutput_graph, 'timestamp')
            ylabel(app.solarOutput_graph, 'Energy (MW·h)')
            zlabel(app.solarOutput_graph, 'Z')
            app.solarOutput_graph.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.WindturbineparametersPanel = uipanel(app.UIFigure);
            app.WindturbineparametersPanel.Enable = 'off';
            app.WindturbineparametersPanel.Title = 'Wind turbine parameters';
            app.WindturbineparametersPanel.Visible = 'off';
            app.HubheightmLabel = uilabel(app.WindturbineparametersPanel);
            app.HubheightmLabel.Text = 'Hub height (m):';
            app.dropdown_hubH = uidropdown(app.WindturbineparametersPanel);
            app.dropdown_hubH.Items = {'25', '50', '80', '100'};
            app.dropdown_hubH.Value = '50';
            app.BladelengthmEditFieldLabel = uilabel(app.WindturbineparametersPanel);
            app.BladelengthmEditFieldLabel.Text = 'Blade length (m):';
            app.edit_bladeLength = uieditfield(app.WindturbineparametersPanel, 'numeric');
            app.edit_bladeLength.Limits = [0 Inf];
            app.edit_bladeLength.Value = 20;
            app.Airdensitykgm3Label = uilabel(app.WindturbineparametersPanel);
            app.Airdensitykgm3Label.Text = 'Air density(kg/m^3):';
            app.edit_airDensity = uieditfield(app.WindturbineparametersPanel, 'numeric');
            app.edit_airDensity.Limits = [0 Inf];
            app.edit_airDensity.Value = 1.225;
            app.EfficiencyLabel = uilabel(app.WindturbineparametersPanel);
            app.EfficiencyLabel.Text = 'Efficiency (%):';
            app.edit_turbineEfficiency = uieditfield(app.WindturbineparametersPanel, 'numeric');
            app.edit_turbineEfficiency.Limits = [0 100];
            app.edit_turbineEfficiency.Value = 20;
            app.btn_windEnergy = uibutton(app.WindturbineparametersPanel, 'state');
            app.btn_windEnergy.ValueChangedFcn = createCallbackFcn(app, @estimate_windE, true);
            app.btn_windEnergy.Text = 'Estimate Wind Energy';
            app.SolarenergyparametersPanel = uipanel(app.UIFigure);
            app.SolarenergyparametersPanel.Enable = 'off';
            app.SolarenergyparametersPanel.Title = 'Solar energy parameters';
            app.SolarenergyparametersPanel.Visible = 'off';
            app.Panelaream2Label = uilabel(app.SolarenergyparametersPanel);
            app.Panelaream2Label.Text = 'Panel area (m^2):';
            app.edit_panelArea = uieditfield(app.SolarenergyparametersPanel, 'numeric');
            app.edit_panelArea.Limits = [0 Inf];
            app.edit_panelArea.Value = 10;
            app.EfficiencyLabel_2 = uilabel(app.SolarenergyparametersPanel);
            app.EfficiencyLabel_2.Text = 'Efficiency (%):';
            app.edit_panelEfficiency = uieditfield(app.SolarenergyparametersPanel, 'numeric');
            app.edit_panelEfficiency.Limits = [0 100];
            app.edit_panelEfficiency.Value = 20;
            app.btn_solarEnergy = uibutton(app.SolarenergyparametersPanel, 'state');
            app.btn_solarEnergy.ValueChangedFcn = createCallbackFcn(app, @estimate_solarE, true);
            app.btn_solarEnergy.Text = 'Estimate Solar Energy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end